classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DviewMenu                     matlab.ui.container.Menu
        InfoMenu                      matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        Menu2                         matlab.ui.container.Menu
        tempsrestantEditField         matlab.ui.control.EditField
        tempsrestantEditFieldLabel    matlab.ui.control.Label
        tempscouleEditField           matlab.ui.control.EditField
        tempscoulEditFieldLabel       matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        DviewTab                      matlab.ui.container.Tab
        UIAxes3D                      matlab.ui.control.UIAxes
        distTab                       matlab.ui.container.Tab
        UIAxesDistToTargets           matlab.ui.control.UIAxes
        UIAxesBetwDrones              matlab.ui.control.UIAxes
        PosTab                        matlab.ui.container.Tab
        DronesperdusEditField         matlab.ui.control.NumericEditField
        DronesperdusEditFieldLabel    matlab.ui.control.Label
        DronesrestantsEditField       matlab.ui.control.NumericEditField
        DronesrestantsEditFieldLabel  matlab.ui.control.Label
        FPSEditField                  matlab.ui.control.NumericEditField
        FPSEditFieldLabel             matlab.ui.control.Label
        Console                       matlab.ui.control.TextArea
        Lamp                          matlab.ui.control.Lamp
        InfoTextAreaLabel             matlab.ui.control.Label
        CoorDropDown                  matlab.ui.control.DropDown
        CoorDropDownLabel             matlab.ui.control.Label
        TargetDropDown                matlab.ui.control.DropDown
        TargetDropDownLabel           matlab.ui.control.Label
        EditField                     matlab.ui.control.NumericEditField
        EditFieldLabel                matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        DropDownLabel                 matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        Slider                        matlab.ui.control.Slider
        SliderLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        backend;
        IsRunning = false;
        
        %graphique
        targets;
        heads;
        quiver_handles;

        %temps
        simu_duration;
        dt;
        current_time;

        %dimension environnement
        dims;

        %graphiques 2D
        avg_pos_history = [];    % Historique de la norme des positions
        avg_pos_x_history = [];  % Historique des positions moyennes en X
        avg_pos_y_history = [];  
        avg_pos_z_history = [];  
        dist_to_target_history;


    end

    
    methods (Access = private)
        
        function update(app)
            app.backend.update();
            % Pause optionnelle pour contrôler la vitesse de visualisation
            pause(0.001);  % Ajuster ou supprimer selon les besoins
            %drawnow limitrate;
            %app.tempsrestantEditField.Value = num2str(app.tempsrestantEditField.Value - app.dt);
            %app.tempscouleEditField.Value = num2str(app.tempscouleEditField.Value + app.dt);
            app.updatePlot();
            app.update2DPlot();
        end
        
        function RunSimulation(app)
            while app.IsRunning
                app.update();
                drawnow;
                pause(0.01);
            end

        end
        
        function InitPlot(app)
            app.UIAxes3D.XLim = [app.dims(1,1), app.dims(1,2)];
            app.UIAxes3D.YLim = [app.dims(2,1), app.dims(2,2)];
            app.UIAxes3D.ZLim = [app.dims(3,1), app.dims(3,2)];
            app.UIAxes3D.View = [45, 30];

            xlabel(app.UIAxes3D, 'X');  
            ylabel(app.UIAxes3D, 'Y');  
            zlabel(app.UIAxes3D, 'Altitude (Z)'); 
            title(app.UIAxes3D, 'Rendu de Simulation en Temps Réel'); 
            
            hold(app.UIAxes3D, 'on');

            fill3(app.UIAxes3D, app.backend.env.GroundCoordinates.x, app.backend.env.GroundCoordinates.y, app.backend.env.GroundCoordinates.z, ...
                [0.5 0.5 0.5], 'FaceAlpha', 0.3); % Couleur grise avec transparence

            %Affichage des zones (obstacles)
            for i = 1:length(app.backend.env.ZonesList)
                zone = app.backend.env.ZonesList{i};
        
                % Déterminer la couleur selon la catégorie de la zone
                switch zone.Category
                    case 'A'
                        zoneColor = 'g'; % Zones autorisées en vert
                    case 'P'
                        zoneColor = 'r'; % Zones prohibées en rouge
                    case 'M'
                        zoneColor = 'b'; % Zones de manœuvre en bleu
                    otherwise
                        zoneColor = 'y'; % Autres zones en jaune
                end
        
                % Créer la forme de la zone avec le type, la couleur, la position et les dimensions spécifiées
                create_shape(zone.Type, zoneColor, zone.CenterPosition, zone.Dimensions, zone.TiltAngle, app.UIAxes3D);
            end
            
            %Affichage des drones et des targets
            targets = app.backend.targetList;
            app.targets = gobjects(1, size(targets,1));
            for k = 1:size(targets,1)
                app.targets(k)=scatter3(app.UIAxes3D, targets(k,1), targets(k,2), targets(k,3), 50, 'filled', 'MarkerFaceColor', 'r');
            end
        
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            for i=1:n_drone
                drone_pos(i,:)=app.backend.swarm.AliveDrones{i}.posState;
        
            end
        
            % Initialiser les objets de tracé pour les éléments animés    
            app.heads=gobjects(1,n_drone);
            app.quiver_handles=gobjects(1,n_drone);
        
            for i=1:n_drone
                app.heads(i)=scatter3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 50, 'filled', 'MarkerFaceColor', 'b');
                app.quiver_handles(i)=quiver3(app.UIAxes3D, drone_pos(i,1), drone_pos(i,2), drone_pos(i,3), 0, 0, 0, 'r', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
            end
        end


        function updatePlot(app)
            hold(app.UIAxes3D, 'on');
            n_drone=size(app.backend.swarm.Drones,2);
            drone_pos=zeros(n_drone,3);
            drone_speed=zeros(n_drone,3);
            for i=1:n_drone
                drone = app.backend.swarm.Drones{i};
                drone_pos(i,:)=drone.posState;
                drone_speed(i,:)=drone.speedState;
                if drone.IsAlive
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3));
                    set(app.quiver_handles(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), ...
                     'UData', drone_speed(i,1), 'VData', drone_speed(i,2), 'WData', drone_speed(i,3));
                else
                    set(app.heads(i), 'XData', drone_pos(i,1), 'YData', drone_pos(i,2), 'ZData', drone_pos(i,3), 'MarkerFaceColor', [0 0 0]);
                end
            end

        end
        
        function update2DPlot(app)
            drones_data = app.backend.swarm.AliveDrones;
            targets_data = app.backend.swarm.target_list;
            avg_pos_x = 0;
            avg_pos_y = 0;
            avg_pos_z = 0;
            for i = 1: length(drones_data)

                avg_pos_x = avg_pos_x + drones_data{i}.posState(1);
                avg_pos_y = avg_pos_y + drones_data{i}.posState(2);
                avg_pos_z = avg_pos_z + drones_data{i}.posState(3);
            end

            avg_pos_x = avg_pos_x /length(drones_data);
            avg_pos_y = avg_pos_y /length(drones_data);
            avg_pos_z = avg_pos_z /length(drones_data);

            avg_pos = sqrt(avg_pos_x^2 + avg_pos_y^2 + avg_pos_z^2);
            app.avg_pos_history(end+1) = avg_pos;
            app.avg_pos_x_history(end+1) = avg_pos_x;
            app.avg_pos_y_history(end+1) = avg_pos_y;
            app.avg_pos_z_history(end+1) = avg_pos_z;
            
            hold(app.UIAxesDistToTargets, 'on');
            cla(app.UIAxesDistToTargets); % Nettoyage du tracé précédent 

            app.dist_to_target_history

            for i = 1: size(targets_data, 1)
                x = targets_data(i, 1);
                y = targets_data(i, 2);
                z = targets_data(i, 3);


                dist_to_target = sqrt((x - avg_pos_x)^2 + (y - avg_pos_y)^2 + (z - avg_pos_z)^2);
                app.dist_to_target_history(:, end + 1) = dist_to_target;
                plot(app.UIAxesDistToTargets, app.dist_to_target_history(i,:), '-', 'LineWidth', 2, 'DisplayName', ['Target ', num2str(i)]);
            end
            xlabel(app.UIAxesDistToTargets, 'Time Steps');
            ylabel(app.UIAxesDistToTargets, 'Avg Dist');
            title(app.UIAxesDistToTargets, 'Average distance swarm/targets');
            grid(app.UIAxesDistToTargets, 'on');
            drawnow limitrate;
            
        end

        function InitDropDown(app)
            targetNames = {};
            for i = 1:size(app.backend.targetList, 1)
                targetNames{end+1} = ['target_', num2str(i), '_x'];
                targetNames{end+1} = ['target_', num2str(i), '_y'];
                targetNames{end+1} = ['target_', num2str(i), '_z'];
            end
            app.DropDown.Items = targetNames;
            app.DropDown.Value = 'target_1_x';
        end

        function InitTargetDropDown(app)
            targetNames = {};
            for i = 1:size(app.backend.targetList, 1)
                targetNames{end+1} = [num2str(i)];
            end
            app.TargetDropDown.Items = targetNames;
            %app.TargetDropdown.Value = '1';
        end

        function InitSlider(app)
            %app.Slider.Limits = app.backend.env.GroundDimensions;
            app.Slider.Limits = [-200 200];
        end
        
    end
    
    methods (Access = public)
        
        function OnDronesCollision(app, ID1, ID2)
            disp(['collision entre les drones ' num2str(ID1) ' et ' num2str(ID2)])
            % Création du message à afficher
            message = sprintf('Collision entre drone %d et drone %d', ID1, ID2);     
            currentMessages = app.Console.Value;  
            if isempty(currentMessages{1})  
                currentMessages = currentMessages(2:end);  
            end
            currentMessages{end+1} = message;  
            app.Console.Value = currentMessages;

            delete(app.quiver_handles(ID1));
            delete(app.quiver_handles(ID2));

        end

        function OnObstacleCollision(obj, i, k)

        end
        
        function OnDronesNbUpdate(app)
            app.DronesrestantsEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesperdusEditField.Value = length(app.backend.swarm.DeadDrones);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.backend = AppBackend();
            app.backend.Init(app);

            app.simu_duration = app.backend.settings.temps;
            app.dt = app.backend.settings.dt;
            app.current_time =0.0;

            app.dims = app.backend.settings.dims;

            app.DronesrestantsEditField.Value = length(app.backend.swarm.AliveDrones);
            app.DronesperdusEditField.Value = length(app.backend.swarm.DeadDrones);

            app.dist_to_target_history = NaN(length(app.backend.swarm.target_list), 1);  % Initialiser avec NaN pour identifier les cases non remplies

            app.tempsrestantEditField.Value = num2str(app.simu_duration);
            app.tempscouleEditField.Value = '0';

            app.InitPlot();
            app.InitDropDown();
            app.InitTargetDropDown();
            app.InitSlider();

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            if ~app.IsRunning
                app.IsRunning = true;
                app.RunButton.Text = 'Pause';
                app.Lamp.Color = [0.00,1.00,0.00];
                RunSimulation(app);
            else
                app.IsRunning = false;
                app.RunButton.Text = 'Run';
                app.Lamp.Color = [1.00,0.00,0.00];
            end


        end

        % Clicked callback: DropDown
        function DropDownClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DviewMenu = uimenu(app.UIFigure);
            app.DviewMenu.Text = '3Dview';
            app.InfoMenu = uimenu(app.UIFigure);
            app.InfoMenu.Text = 'Info';
            app.Menu = uimenu(app.InfoMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.InfoMenu);
            app.Menu2.Text = 'Menu2';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Target1_x', 'Target1_y', 'Target1_z', 'Target2_x', 'Target2_y', 'Target2_z'};
            app.DropDown.ClickedFcn = createCallbackFcn(app, @DropDownClicked, true);
            app.DropDown.Value = 'Target1_x';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetDropDownLabel = uilabel(app.UIFigure);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.UIFigure);
            app.TargetDropDown.Items = {'1', '2'};
            app.TargetDropDown.Value = '1';
            app.CoorDropDownLabel = uilabel(app.UIFigure);
            app.CoorDropDownLabel.Text = 'Coor.';
            app.CoorDropDown = uidropdown(app.UIFigure);
            app.CoorDropDown.Items = {'x', 'y', 'z'};
            app.CoorDropDown.Value = 'x';
            app.InfoTextAreaLabel = uilabel(app.UIFigure);
            app.InfoTextAreaLabel.Text = 'Info';
            app.Lamp = uilamp(app.UIFigure);
            app.Console = uitextarea(app.UIFigure);
            app.FPSEditFieldLabel = uilabel(app.UIFigure);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.UIFigure, 'numeric');
            app.DronesrestantsEditFieldLabel = uilabel(app.UIFigure);
            app.DronesrestantsEditFieldLabel.Text = 'Drones restants';
            app.DronesrestantsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DronesperdusEditFieldLabel = uilabel(app.UIFigure);
            app.DronesperdusEditFieldLabel.Text = 'Drones perdus';
            app.DronesperdusEditField = uieditfield(app.UIFigure, 'numeric');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DviewTab = uitab(app.TabGroup);
            app.DviewTab.Title = '3Dview';
            app.UIAxes3D = uiaxes(app.DviewTab);
            title(app.UIAxes3D, '3DGraph')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.distTab = uitab(app.TabGroup);
            app.distTab.Title = 'dist';
            app.UIAxesBetwDrones = uiaxes(app.distTab);
            title(app.UIAxesBetwDrones, 'dist. between drones')
            xlabel(app.UIAxesBetwDrones, 'X')
            ylabel(app.UIAxesBetwDrones, 'Y')
            zlabel(app.UIAxesBetwDrones, 'Z')
            app.UIAxesDistToTargets = uiaxes(app.distTab);
            title(app.UIAxesDistToTargets, 'dist to targets')
            xlabel(app.UIAxesDistToTargets, 'X')
            ylabel(app.UIAxesDistToTargets, 'Y')
            zlabel(app.UIAxesDistToTargets, 'Z')
            app.PosTab = uitab(app.TabGroup);
            app.PosTab.Title = 'Pos';
            app.tempscoulEditFieldLabel = uilabel(app.UIFigure);
            app.tempscoulEditFieldLabel.Text = 'temps écoulé';
            app.tempscouleEditField = uieditfield(app.UIFigure, 'text');
            app.tempsrestantEditFieldLabel = uilabel(app.UIFigure);
            app.tempsrestantEditFieldLabel.Text = 'temps restant';
            app.tempsrestantEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end